classdef covid_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AverageofdaysSlider        matlab.ui.control.Slider
        AverageofdaysSliderLabel   matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryListBoxLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AveragingWindow % Value of the Average slider
        Averages % Moving averages of cases/deaths

        Location % May be a country or a state - "string"
        CountryIDX % Index of selected Country - "integer"
        StateIDX % Index of current state - "integer"

        ShowCases % COVID cases - "boolean"
        ShowDeaths % COVID deaths - "boolean"
        ShowCummulative % Cummulative cases/deaths - "boolean"
        ShowDaily % Daily cases/deaths - "boolean"
        GlobalSelected % Global COVID cases

        Dates % "string"
        Countries % "struct array with fields Name, States and Cases"
        CountriesNames % array of strings
        StatesNames % array of strings
        StateCases % "cell array of integers ({[x y] [a b]})"
    end
    
    methods (Access = private)

        function setupCountries(app, data)
            function result = sumCellArrays(v, w)
                pair_1 = v{1};
                pair_2 = w{1};
                result = pair_1 + pair_2;
            end
            n_dates = length(app.Dates);
            
            repeated_countries_names = data(2:end, 1); % Some country names repeat
            countries_names = unique(repeated_countries_names);
            n_countries = length(countries_names) + 1;
            
            states_names = data(2:end, 2);
            states_per_country = repmat({''}, n_countries, 1); % Preallocate

            cases_number = data(2:end, 3:end);
            cases_per_country = repmat({[0 0]}, n_countries, 1); % Preallocate

            % Global Settings
            countries_names = vertcat({'Global'}, countries_names);
            states_per_country{1} = {'All'};
            cases_per_country{1} = repmat({[0 0]}, 1, n_dates);
            
            % Rest of the countries
            for ii = 2:n_countries
                country_name = countries_names{ii};
                ii_first = find(strcmp(repeated_countries_names, country_name), 1, "first");
                ii_last = find(strcmp(repeated_countries_names, country_name), 1, "last");
                states_per_country{ii} = states_names(ii_first:ii_last);
                states_per_country{ii}(1) = {'All'};

                cases_per_country{ii} = cases_number(ii_first:ii_last, :);
                % Global cases
                % 
                % cases_per_country{1}(1) = 20;
                for jj = 1:n_dates
                    cases_country_ii_day_jj = cases_per_country{ii}(jj);
                    cases_global_day_jj = cases_per_country{1}(jj);
                    cases_per_country{1}{jj} = sumCellArrays(cases_country_ii_day_jj, cases_global_day_jj);
                end
            end

            % Widgets
            app.CountryListBox.Items = countries_names;
            app.StateorRegionListBox.Items = states_per_country{1};

            % Properties
            app.Countries = struct('Name', countries_names, 'States', states_per_country, 'Cases', cases_per_country);
            app.CountriesNames = countries_names; 
            app.StatesNames = states_per_country{1};
            app.StateCases = cases_per_country{1};
        end


        function result = getCasesRow(app, show_cases)

            function result = getDaily(v)
                w = zeros(1, length(v));
                w(1) = v(1);
                for ii = 2:length(v)
                    w(ii) = v(ii) - v(ii-1);
                end
                result = w;
            end

            [m, ~] = size(app.StateCases);
            if m == 1
                cases_number = [app.StateCases{:}];
            else
                tmp = app.StateCases(app.StateIDX,:);
                cases_number = [tmp{:}];
            end

            if show_cases
                cases_number = cases_number(1:2:end);
            else % show_deaths
                cases_number = cases_number(2:2:end);
            end

            if app.ShowDaily
                result = getDaily(cases_number);
            else % Show Cummulative
                result = cases_number;
            end
        end

        function setUIAxesTitle(app)
            if app.ShowCases && app.ShowDeaths
                cases = "Cases and Deaths";
            elseif app.ShowCases
                cases = "Cases";
            else
                cases = "Deaths";
            end
            location = app.Location;
            if isequal(location, 'Global')
                location = 'the world';
            end
            mean = round(app.AveragingWindow);
            graph_title = sprintf("%s of COVID-19 in %s (%d-day mean)", cases, location, mean);
            title(app.UIAxes, graph_title);
        end

        function setPlotConfig(app)
            yyaxis(app.UIAxes,"left");
            cla(app.UIAxes);
            yyaxis(app.UIAxes,"right");
            cla(app.UIAxes);
            grid(app.UIAxes, "on");
            xlim(app.UIAxes,'manual');
            ylim(app.UIAxes, 'auto');

            % Unable altering limits when clicking, holding and dragging
            set(app.UIAxes,'HitTest','off', 'PickableParts', 'visible');

            app.setUIAxesTitle();
            % app.UIAxes.ActivePositionProperty = 'position';
        end
        
        function plotData(app)
            
            % Set configuration of plot
            app.setPlotConfig();

            date_times = datetime(app.Dates,"InputFormat",'MM/dd/yy');
            
            if app.ShowCases
                app.computeAverages(true);
                yyaxis(app.UIAxes,"left");
                bar(app.UIAxes, date_times, app.Averages);
                hold(app.UIAxes,'on');
            end

            if app.ShowDeaths
                app.computeAverages(false);
                yyaxis(app.UIAxes,"right");
                plot(app.UIAxes, date_times, app.Averages);
            end
            hold(app.UIAxes,'off');
        end
        
        function computeAverages(app, show_cases)
            app.Averages = movmean(app.getCasesRow(show_cases), app.AveragingWindow);
        end

        function setDefaults(app)

            % Set Widget Values
            app.AverageofdaysSlider.Value = app.AverageofdaysSlider.Limits(1);

            % Set Properties
            app.AveragingWindow = app.AverageofdaysSlider.Value;
            app.ShowCases = true;
            app.ShowDeaths = false;
            app.ShowCummulative = true;
            app.ShowDaily = false;
            app.StateIDX = 1;
            app.GlobalSelected = true;
            app.Location = 'Global';
            
            app.computeAverages(app.ShowCases);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.Dates = covid_data(1, 3:end);
            app.setupCountries(covid_data);
            app.setDefaults();
            app.plotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            state_name = app.StateorRegionListBox.Value;
            country = app.Countries(app.CountryIDX);
            state_id = find(strcmp(country.States, state_name), 1);
            app.StateIDX = state_id;
            
            % Plot
            app.Location = state_name;
            app.plotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            country_name = app.CountryListBox.Value;
            country_id = find(strcmp(app.CountriesNames, country_name), 1);
            country_states = app.Countries(country_id).States;
            country_cases = app.Countries(country_id).Cases;
            % Widget
            app.StateorRegionListBox.Items = country_states;
            % Property
            app.CountryIDX = country_id;
            app.StateCases = country_cases;

            % Plot
            app.Location = country_name;
            app.plotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Cases'
                    app.ShowCases = true;
                    app.ShowDeaths = false;
                case 'Deaths'
                    app.ShowCases = false;
                    app.ShowDeaths = true;
                case 'Both'
                    app.ShowCases = true;
                    app.ShowDeaths = true;
            end
            app.plotData();
        end

        % Value changed function: AverageofdaysSlider
        function AverageofdaysSliderValueChanged(app, event)
            app.AveragingWindow = app.AverageofdaysSlider.Value;
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Daily'
                    app.ShowDaily = true;
                    app.ShowCummulative = false;
                case 'Cumulative'
                    app.ShowDaily = false;
                    app.ShowCummulative = true;
            end
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AverageofdaysSliderLabel = uilabel(app.UIFigure);
            app.AverageofdaysSliderLabel.Text = 'Average # of days';
            app.AverageofdaysSlider = uislider(app.UIFigure);
            app.AverageofdaysSlider.Limits = [1 28];
            app.AverageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanged, true);
            app.AverageofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end